classdef PowerExchangeOptimisationAlgorithm_UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExecuteButton                   matlab.ui.control.Button
        ElectricVehicleBatteryPanel     matlab.ui.container.Panel
        P_maxkWEditFieldLabel           matlab.ui.control.Label
        P_maxkWEditField                matlab.ui.control.NumericEditField
        P_minkWEditFieldLabel           matlab.ui.control.Label
        P_minkWEditField                matlab.ui.control.NumericEditField
        EVBatteryCapacitykWhEditFieldLabel  matlab.ui.control.Label
        EVBatteryCapacitykWhEditField   matlab.ui.control.NumericEditField
        SoC_initialkWhEditFieldLabel    matlab.ui.control.Label
        SoC_initialkWhEditField         matlab.ui.control.NumericEditField
        ConverterEfficiencyEditFieldLabel  matlab.ui.control.Label
        ConverterEfficiencyEditField    matlab.ui.control.NumericEditField
        CommutesettingsPanel            matlab.ui.container.Panel
        SoC_commutekWhEditFieldLabel    matlab.ui.control.Label
        SoC_commutekWhEditField         matlab.ui.control.EditField
        EVDeparturehoursinceoptimisationstartSpinnerLabel  matlab.ui.control.Label
        EVDeparturehoursinceoptimisationstartSpinner  matlab.ui.control.Spinner
        EVReturnhoursinceoptimisationstartSpinnerLabel  matlab.ui.control.Label
        EVReturnhoursinceoptimisationstartSpinner  matlab.ui.control.Spinner
        CommuteSwitchLabel              matlab.ui.control.Label
        CommuteSwitch                   matlab.ui.control.Switch
        SoC_targetkWhEditFieldLabel     matlab.ui.control.Label
        SoC_targetkWhEditField          matlab.ui.control.NumericEditField
        ElectricVehicleSwitchLabel      matlab.ui.control.Label
        ElectricVehicleSwitch           matlab.ui.control.Switch
        RegularBatteryPanel             matlab.ui.container.Panel
        P_maxkWEditField_2Label         matlab.ui.control.Label
        P_maxkWEditField_2              matlab.ui.control.NumericEditField
        P_minkWEditField_2Label         matlab.ui.control.Label
        P_minkWEditField_2              matlab.ui.control.NumericEditField
        BatteryCapacitykWhEditFieldLabel  matlab.ui.control.Label
        BatteryCapacitykWhEditField     matlab.ui.control.NumericEditField
        SoC_initialkWhEditField_2Label  matlab.ui.control.Label
        SoC_initialkWhEditField_2       matlab.ui.control.NumericEditField
        ConverterEfficiencyEditField_2Label  matlab.ui.control.Label
        ConverterEfficiencyEditField_2  matlab.ui.control.NumericEditField
        SoC_targetkWhEditField_2Label   matlab.ui.control.Label
        SoC_targetkWhEditField_2        matlab.ui.control.NumericEditField
        BatterySwitchLabel              matlab.ui.control.Label
        BatterySwitch                   matlab.ui.control.Switch
        SolarPanels                     matlab.ui.container.Panel
        SolarPanelsSwitchLabel          matlab.ui.control.Label
        SolarPanelsSwitch               matlab.ui.control.Switch
        SolarPanelRatedPowerkWEditFieldLabel  matlab.ui.control.Label
        SolarPanelRatedPowerkWEditField  matlab.ui.control.NumericEditField
        SellingcostofPVPowerkWhEditFieldLabel  matlab.ui.control.Label
        SellingcostofPVPowerkWhEditField  matlab.ui.control.NumericEditField
        LoadShiftingPanel               matlab.ui.container.Panel
        LoadShiftingEditFieldLabel      matlab.ui.control.Label
        LoadShiftingEditField           matlab.ui.control.NumericEditField
        ShiftingSwitchLabel             matlab.ui.control.Label
        ShiftingSwitch                  matlab.ui.control.Switch
        GridPanel                       matlab.ui.container.Panel
        P_grid_maxkWEditFieldLabel      matlab.ui.control.Label
        P_grid_maxkWEditField           matlab.ui.control.NumericEditField
        P_grid_minkWEditFieldLabel      matlab.ui.control.Label
        P_grid_minkWEditField           matlab.ui.control.NumericEditField
        ThermodynamicModelPanel         matlab.ui.container.Panel
        AirDensitykgm3EditFieldLabel    matlab.ui.control.Label
        AirDensitykgm3EditField         matlab.ui.control.NumericEditField
        HeatCapacityCkJkgCEditFieldLabel  matlab.ui.control.Label
        HeatCapacityCkJkgCEditField     matlab.ui.control.NumericEditField
        AirVolumeVm3EditFieldLabel      matlab.ui.control.Label
        AirVolumeVm3EditField           matlab.ui.control.NumericEditField
        U_wallkWm2CEditFieldLabel       matlab.ui.control.Label
        U_wallkWm2CEditField            matlab.ui.control.NumericEditField
        U_windowkWm2CEditFieldLabel     matlab.ui.control.Label
        U_windowkWm2CEditField          matlab.ui.control.NumericEditField
        WallSurfacem2EditFieldLabel     matlab.ui.control.Label
        WallSurfacem2EditField          matlab.ui.control.NumericEditField
        WindowSurfacem2EditFieldLabel   matlab.ui.control.Label
        WindowSurfacem2EditField        matlab.ui.control.NumericEditField
        awEditFieldLabel                matlab.ui.control.Label
        awEditField                     matlab.ui.control.NumericEditField
        R_sejm2CWEditFieldLabel         matlab.ui.control.Label
        R_sejm2CWEditField              matlab.ui.control.NumericEditField
        windowEditFieldLabel            matlab.ui.control.Label
        windowEditField                 matlab.ui.control.NumericEditField
        SCEditFieldLabel                matlab.ui.control.Label
        SCEditField                     matlab.ui.control.NumericEditField
        HeatingCoolingsystemPanel       matlab.ui.container.Panel
        InitialIndoorTemperatureCEditFieldLabel  matlab.ui.control.Label
        InitialIndoorTemperatureCEditField  matlab.ui.control.NumericEditField
        MaxIndoorTemperatureCEditFieldLabel  matlab.ui.control.Label
        MaxIndoorTemperatureCEditField  matlab.ui.control.NumericEditField
        MinIndoorTemperatureCEditFieldLabel  matlab.ui.control.Label
        MinIndoorTemperatureCEditField  matlab.ui.control.NumericEditField
        MaxHeatingCoolingPowerkWLabel   matlab.ui.control.Label
        MaxHeatingCoolingPowerkWEditField  matlab.ui.control.NumericEditField
        MinHeatingCoolingPowerkWEditFieldLabel  matlab.ui.control.Label
        MinHeatingCoolingPowerkWEditField  matlab.ui.control.NumericEditField
        COPEditFieldLabel               matlab.ui.control.Label
        COPEditField                    matlab.ui.control.NumericEditField
        HeatingSystemSwitchLabel        matlab.ui.control.Label
        HeatingSystemSwitch             matlab.ui.control.Switch
        AdvancedSettingsPanel           matlab.ui.container.Panel
        PSOParametersPanel              matlab.ui.container.Panel
        EVBatterydtDropDownLabel        matlab.ui.control.Label
        EVBatterydtDropDown             matlab.ui.control.DropDown
        ThermaldtDropDownLabel          matlab.ui.control.Label
        ThermaldtDropDown               matlab.ui.control.DropDown
        LoadshiftingdtDropDownLabel     matlab.ui.control.Label
        LoadshiftingdtDropDown          matlab.ui.control.DropDown
        RegularBatterydtDropDownLabel   matlab.ui.control.Label
        RegularBatterydtDropDown        matlab.ui.control.DropDown
        SimulationsettingsPanel         matlab.ui.container.Panel
        ScenarioSelectionDropDownLabel  matlab.ui.control.Label
        ScenarioSelectionDropDown       matlab.ui.control.DropDown
        TimestepdthoursEditFieldLabel   matlab.ui.control.Label
        TimestepdthoursEditField        matlab.ui.control.NumericEditField
        AdvancedSettingsCheckBox        matlab.ui.control.CheckBox
        OptimisationstartDropDownLabel  matlab.ui.control.Label
        OptimisationstartDropDown       matlab.ui.control.DropDown
        PSOParticlesEditFieldLabel      matlab.ui.control.Label
        PSOParticlesEditField           matlab.ui.control.NumericEditField
        PSOEpochsEditFieldLabel         matlab.ui.control.Label
        PSOEpochsEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        CostOfPower
        CostOfPV
        PV_Production
        home_load
        SoC_initial_ev
        SoC_commute
        Tmax
        Tmin
        T_in_initial
        Alpha
        Beta
        Zeta
        SoC_target_ev
        dt
        outdoor_temperature
        V
        C
        rho
        Uwall
        Uwindow
        Fwall
        Fwindow
        aw
        Rsej
        taf_window
        SC
        Qin
        Qsw
        Qsg
        QEC_max
        QEC_min
        COP
        dim1
        dim2
        dim3
        dim4
        heatcool_sign
        SoC_initial_bat
        SoC_target_bat
        EV_bat_n
        bat_n
        scenario
        solar_panels_state
        solar_panel_power
        Pmax_ev
        Pmin_ev
        Pmax_bat
        Pmin_bat
        Load_shifting
        P_grid_max
        P_grid_min
        solar_radiation
        U
        U_initial
        ps
        epoch
        OptimisationStartTime
        PSO_evbat_dt
        PSO_thermal_dt
        PSO_loadshift_dt
        PSO_regbat_dt
        t_leave
        t_return
        EV_flag
        Battery_flag
        HeatSystem_flag
        Loadshift_flag
        Ev_bat_capacity
        bat_capacity
        SoCmax_ev
        SoCmin_ev
        SoCmax_bat
        SoCmin_bat
        commute_enable
        SellingCostOfPower
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            close all;
            app.scenario=app.ScenarioSelectionDropDown.Value;
            app.solar_panels_state=app.SolarPanelsSwitch.Value;
            if app.SolarPanelsSwitch.Value=="Off"
                app.SolarPanelRatedPowerkWEditField.Enable="Off";
                app.SellingcostofPVPowerkWhEditField.Enable="Off";
            end
            if app.SolarPanelsSwitch.Value=="On"
                app.SolarPanelRatedPowerkWEditField.Enable="On";
                app.SellingcostofPVPowerkWhEditField.Enable="On";
            end
            app.solar_panel_power=app.SolarPanelRatedPowerkWEditField.Value;
            app.Pmax_ev = app.P_maxkWEditField.Value;
            app.Pmin_ev = app.P_minkWEditField.Value;
            app.SoC_initial_ev = app.SoC_initialkWhEditField.Value;
            app.EV_bat_n = app.ConverterEfficiencyEditField.Value;
            app.SoC_target_ev = app.SoC_targetkWhEditField.Value;
            app.SoC_commute = str2double(app.SoC_commutekWhEditField.Value);
            app.Pmax_bat = app.P_maxkWEditField_2.Value;
            app.Pmin_bat = app.P_minkWEditField_2.Value;
            app.SoC_initial_bat = app.SoC_initialkWhEditField_2.Value;
            app.SoC_target_bat = app.SoC_targetkWhEditField_2.Value;
            app.bat_n = app.ConverterEfficiencyEditField_2.Value;
            app.CostOfPV = app.SellingcostofPVPowerkWhEditField.Value;
            app.Load_shifting = app.LoadShiftingEditField.Value;
            app.dt = app.TimestepdthoursEditField.Value;
            app.P_grid_max = app.P_grid_maxkWEditField.Value;
            app.P_grid_min = app.P_grid_minkWEditField.Value;
            app.V = app.AirDensitykgm3EditField.Value;
            app.C = app.HeatCapacityCkJkgCEditField.Value;
            app.rho = app.AirVolumeVm3EditField.Value;
            app.aw = app.awEditField.Value;
            app.Rsej = app.R_sejm2CWEditField.Value;
            app.SC = app.SCEditField.Value;
            app.Uwall = app.U_wallkWm2CEditField.Value;
            app.Uwindow = app.U_windowkWm2CEditField.Value;
            app.Fwall = app.WallSurfacem2EditField.Value;
            app.Fwindow = app.WindowSurfacem2EditField.Value;
            app.taf_window = app.windowEditField.Value;
            app.T_in_initial = app.InitialIndoorTemperatureCEditField.Value;
            app.Tmax = app.MaxIndoorTemperatureCEditField.Value;
            app.Tmin = app.MinIndoorTemperatureCEditField.Value;
            app.QEC_max = app.MaxHeatingCoolingPowerkWEditField.Value;
            app.QEC_min = app.MinHeatingCoolingPowerkWEditField.Value;
            app.COP = app.COPEditField.Value;
            app.ps = app.PSOParticlesEditField.Value;
            app.epoch = app.PSOEpochsEditField.Value;
            app.OptimisationStartTime = str2double(app.OptimisationstartDropDown.Value);
            app.PSO_evbat_dt = str2double(app.EVBatterydtDropDown.Value);
            app.PSO_thermal_dt = str2double(app.ThermaldtDropDown.Value);
            app.PSO_loadshift_dt = str2double(app.LoadshiftingdtDropDown.Value);
            app.PSO_regbat_dt = str2double(app.RegularBatterydtDropDown.Value);
            app.t_leave = app.EVDeparturehoursinceoptimisationstartSpinner.Value+1;
            app.t_return = app.EVReturnhoursinceoptimisationstartSpinner.Value+1;
            app.EV_flag = app.ElectricVehicleSwitch.Value;
            app.Battery_flag = app.BatterySwitch.Value;
            if app.BatterySwitch.Value == "Off"
                app.P_maxkWEditField_2.Enable="Off";
                app.P_minkWEditField_2.Enable="Off";
                app.BatteryCapacitykWhEditField.Enable="Off";
                app.SoC_initialkWhEditField_2.Enable="Off";
                app.SoC_targetkWhEditField_2.Enable="Off";
                app.ConverterEfficiencyEditField_2.Enable="Off";
            end
            if app.BatterySwitch.Value == "On"
                app.P_maxkWEditField_2.Enable="On";
                app.P_minkWEditField_2.Enable="On";
                app.BatteryCapacitykWhEditField.Enable="On";
                app.SoC_initialkWhEditField_2.Enable="On";
                app.SoC_targetkWhEditField_2.Enable="On";
                app.ConverterEfficiencyEditField_2.Enable="On";
            end
            if app.ElectricVehicleSwitch.Value=="Off"
                app.P_maxkWEditField.Enable="Off";
                app.P_minkWEditField.Enable="Off";
                app.EVBatteryCapacitykWhEditField.Enable="Off";
                app.SoC_initialkWhEditField.Enable="Off";
                app.SoC_targetkWhEditField.Enable="Off";
                app.ConverterEfficiencyEditField.Enable="Off";
                app.SoC_commutekWhEditField.Enable="Off";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="Off";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="Off";
            end
            if app.ElectricVehicleSwitch.Value=="On"
                app.P_maxkWEditField.Enable="On";
                app.P_minkWEditField.Enable="On";
                app.EVBatteryCapacitykWhEditField.Enable="On";
                app.SoC_initialkWhEditField.Enable="On";
                app.SoC_targetkWhEditField.Enable="On";
                app.ConverterEfficiencyEditField.Enable="On";
                app.SoC_commutekWhEditField.Enable="On";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="On";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="On";
            end
            app.Loadshift_flag = app.ShiftingSwitch.Value;
            if app.ShiftingSwitch.Value == "Off"
                app.LoadShiftingEditField.Enable="Off";
            end
            if app.ShiftingSwitch.Value == "On"
                app.LoadShiftingEditField.Enable="On";
            end
            app.HeatSystem_flag = app.HeatingSystemSwitch.Value;
            if app.HeatingSystemSwitch.Value=="Off"
                app.AirDensitykgm3EditField.Enable="Off";
                app.HeatCapacityCkJkgCEditField.Enable="Off";
                app.AirVolumeVm3EditField.Enable="Off";
                app.awEditField.Enable="Off";
                app.R_sejm2CWEditField.Enable="Off";
                app.U_wallkWm2CEditField.Enable="Off";
                app.U_windowkWm2CEditField.Enable="Off";
                app.WallSurfacem2EditField.Enable="Off";
                app.WindowSurfacem2EditField.Enable="Off";
                app.windowEditField.Enable="Off";
                app.SCEditField.Enable="Off";
                app.InitialIndoorTemperatureCEditField.Enable="Off";
                app.MaxIndoorTemperatureCEditField.Enable="Off";
                app.MinIndoorTemperatureCEditField.Enable="Off";
                app.MaxHeatingCoolingPowerkWEditField.Enable="Off";
                app.MinHeatingCoolingPowerkWEditField.Enable="Off";
                app.COPEditField.Enable="Off";
            end
            if app.HeatingSystemSwitch.Value=="On"
                app.AirDensitykgm3EditField.Enable="On";
                app.HeatCapacityCkJkgCEditField.Enable="On";
                app.AirVolumeVm3EditField.Enable="On";
                app.awEditField.Enable="On";
                app.R_sejm2CWEditField.Enable="On";
                app.U_wallkWm2CEditField.Enable="On";
                app.U_windowkWm2CEditField.Enable="On";
                app.WallSurfacem2EditField.Enable="On";
                app.WindowSurfacem2EditField.Enable="On";
                app.windowEditField.Enable="On";
                app.SCEditField.Enable="On";
                app.InitialIndoorTemperatureCEditField.Enable="On";
                app.MaxIndoorTemperatureCEditField.Enable="On";
                app.MinIndoorTemperatureCEditField.Enable="On";
                app.MaxHeatingCoolingPowerkWEditField.Enable="On";
                app.MinHeatingCoolingPowerkWEditField.Enable="On";
                app.COPEditField.Enable="On";
            end
            if app.AdvancedSettingsCheckBox.Value==1
                app.AdvancedSettingsPanel.Visible="On";
                app.ThermodynamicModelPanel.Visible="Off";
            else
                app.AdvancedSettingsPanel.Visible="Off";
                app.ThermodynamicModelPanel.Visible="On";
            end
            app.Ev_bat_capacity = app.EVBatteryCapacitykWhEditField.Value;
            app.bat_capacity = app.BatteryCapacitykWhEditField.Value;
            app.commute_enable = app.CommuteSwitch.Value;
            if app.CommuteSwitch.Value == "Off"
                app.SoC_commutekWhEditField.Enable="Off";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="Off";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="Off";
            elseif app.CommuteSwitch.Value == "On"
                app.SoC_commutekWhEditField.Enable="On";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="On";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="On";
            end
            
        end

        % Value changed function: ScenarioSelectionDropDown
        function ScenarioSelectionDropDownValueChanged(app, event)
            app.scenario = app.ScenarioSelectionDropDown.Value;
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            close all;
            
            
            frequency_support = 0;
            grid_outage = 0;
            dumb_operation = 1;
            
            %% ------------General Initialization -----------------------
            disp("Optimization started.")
            T=24;
            t = (app.OptimisationStartTime-1)/2:app.dt:T;
            T_end = 24-((app.OptimisationStartTime-1)/2);
            
            solar_radiation_struct = load('solar_radiation.mat');
            
            if app.scenario == '1'||app.scenario =='2'||app.scenario =='5'||app.scenario =='6'
                outdoor_temperature_struct = load('savedtemp.mat');
            elseif app.scenario == '3'||app.scenario =='4'||app.scenario =='7'||app.scenario =='8'
                outdoor_temperature_struct = load('winter_daily_temperature.mat');
            end
            
            Load_shifting_max = 1+(app.Load_shifting/100);
            Load_shifting_min = 1-(app.Load_shifting/100);
            
            evening_summer_load_struct = load('evening_summer_load.mat');
            evening_summer_load=evening_summer_load_struct.homeload_down_2;
            
            evening_winter_load_struct = load('evening_winter_load.mat');
            evening_winter_load=evening_winter_load_struct.homeload_down_2;
            
            morning_winter_load_struct = load('morning_winter_load.mat');
            morning_winter_load=morning_winter_load_struct.homeload_down_2;
            
            %             morning_summer_load_struct = load('morning_summer_load.mat');
            %             morning_summer_load=morning_summer_load_struct.homeload_down_2;
            
            morning_summer_load_struct = load('NNload.mat');
            test_load = morning_summer_load_struct.plot_predicted_data / 1000;
            morning_summer_load=test_load(:,1:1.95:end);
            
            
            COP_Xeim_Var_struct = load('cop_xeim_var.mat');
            COP_Xeim_Var=COP_Xeim_Var_struct.vq1;               %Cost of Power - Winter - Variable
            COP_Ther_Var_struct = load('saved_cost_temp.mat');
            COP_Ther_Var=COP_Ther_Var_struct.saved_cost_temp';               %Cost of Power - Summer - Variable
            %             mean_var = mean(COP_Ther_Var);
            %             dCOP_Ther_Var = COP_Ther_Var-mean_var;
            %             COP_Ther_Var = mean_var-dCOP_Ther_Var;
            COP_Xeim_2Z = zeros(1,length(t));   %Cost of Power - Winter - Peak/Off-Peak
            COP_Xeim_2Z(1:4)= 0.11058; COP_Xeim_2Z(5:16)=0.07897; COP_Xeim_2Z(17:30)= 0.11058; COP_Xeim_2Z(30:34)=0.07897; COP_Xeim_2Z(35:49)= 0.11058; %€/kwh
            COP_Ther_2Z = zeros(1,length(t));   %Cost of Power - Summer - Peak/Off-Peak
            COP_Ther_2Z(1:14)= 0.07897; COP_Ther_2Z(15:46)= 0.11058; COP_Ther_2Z(47:49)= 0.07897; %€/kwh
            
            PV_prod_winter_struct = load('PV_prod_winter.mat');
            PV_prod_summer_struct = load('PV_prod_summer.mat');
            
            if app.solar_panels_state=="On"
                PV_Production_winter = app.solar_panel_power*PV_prod_winter_struct.PV_load/1000;
                PV_Production_summer = app.solar_panel_power*PV_prod_summer_struct.PV_Production_summer/1000;
            elseif app.solar_panels_state=="Off"
                PV_Production_winter = 0*PV_prod_winter_struct.PV_load/1000;
                PV_Production_summer = 0*PV_prod_summer_struct.PV_Production_summer/1000;
            else
                disp("Solar panel selection error")
            end
            
            app.SoCmax_ev = app.Ev_bat_capacity*0.9;
            app.SoCmin_ev = app.Ev_bat_capacity*0.1;
            app.SoCmax_bat = app.bat_capacity*0.9;
            app.SoCmin_bat = app.bat_capacity*0.1;
            
            
            switch app.scenario
                case '1'
                    home_load_temp = morning_summer_load;
                    PV_Production_temp= PV_Production_summer;
                    CostOfPower_temp = COP_Ther_2Z;
                    app.heatcool_sign = -1;     %-1 for summer (cooling), +1 for winter (heating)
                case '2'
                    home_load_temp = morning_summer_load;
                    PV_Production_temp= PV_Production_summer;
                    CostOfPower_temp = COP_Ther_Var;
                    app.heatcool_sign = -1;
                case '3'
                    home_load_temp = morning_winter_load;
                    PV_Production_temp= PV_Production_winter;
                    CostOfPower_temp = COP_Xeim_2Z;
                    app.heatcool_sign = +1;
                case '4'
                    home_load_temp = morning_winter_load;
                    PV_Production_temp= PV_Production_winter;
                    CostOfPower_temp = COP_Xeim_Var;
                    app.heatcool_sign = +1;
                case '5'
                    home_load_temp = evening_summer_load;
                    PV_Production_temp= PV_Production_summer;
                    CostOfPower_temp = COP_Ther_2Z;
                    app.heatcool_sign = -1;
                case '6'
                    home_load_temp = evening_summer_load;
                    PV_Production_temp= PV_Production_summer;
                    CostOfPower_temp = COP_Ther_Var;
                    app.heatcool_sign = -1;
                case '7'
                    home_load_temp = evening_winter_load;
                    PV_Production_temp= PV_Production_winter;
                    CostOfPower_temp = COP_Xeim_2Z;
                    app.heatcool_sign = +1;
                case '8'
                    home_load_temp = evening_winter_load;
                    PV_Production_temp=PV_Production_winter;
                    CostOfPower_temp = COP_Xeim_Var;
                    app.heatcool_sign = +1;
                    
                otherwise
                    disp('error')
            end
            
            %% ------------Input Initialization -----------------------
            %             app.outdoor_temperature = outdoor_temperature_struct.data(app.OptimisationStartTime:(T*2)+1);
            app.outdoor_temperature = outdoor_temperature_struct.saved_temp_temp;
            app.outdoor_temperature = app.outdoor_temperature + 0.79;
            app.solar_radiation = solar_radiation_struct.y(app.OptimisationStartTime:(T*2)+1);
            
            app.home_load=home_load_temp(app.OptimisationStartTime:(T*2)+1);
            %             app.home_load=zeros(1,length(t));
            
            app.PV_Production=PV_Production_temp(app.OptimisationStartTime:(T*2)+1);
            app.PV_Production = movmean(app.PV_Production,3);
            
            app.CostOfPower=CostOfPower_temp(app.OptimisationStartTime:(T*2)+1);
            %             app.SellingCostOfPower= 0.95* app.CostOfPower;
            app.SellingCostOfPower= 1* app.CostOfPower;
            
            
            %% ------------Thermodynamic Model Initializations
            
            app.Zeta = (app.rho*app.C*app.V)/(app.dt*3600);     %dt is converted to seconds in order to match the rest of the units
            app.Alpha = 1/app.Zeta;
            app.Beta = (app.Zeta-(app.Uwall*app.Fwall)-(app.Uwindow*app.Fwindow))/app.Zeta;
            app.Qin = 0.2*ones(1,length(t));
            app.Qsw = app.aw * app.Rsej * app.Uwall * app.Fwall * app.solar_radiation;
            app.Qsg = 0.001*app.taf_window * app.SC * app.Fwindow * app.solar_radiation;
            app.U = ((app.Uwall*app.Fwall+app.Uwindow*app.Fwindow)/app.Zeta)*app.outdoor_temperature+(app.Qin+app.Qsw+app.Qsg)/app.Zeta;
            app.U_initial=((app.Uwall*app.Fwall+app.Uwindow*app.Fwindow)/app.Zeta)*app.outdoor_temperature(1);
            
            
            %% ------------Particle Swarm Optimisation-----------
            if app.EV_flag=="On"
                app.dim1 = length((app.OptimisationStartTime-1)/2:app.PSO_evbat_dt:T);
                
                if app.commute_enable=="On"
                    %%%%%%%%%%%%%%%%%% TODO prepei na kanei scale kai me dt
                    %%%%%%%%%%%%%%%%%% kai Dim
                    a = app.t_leave;
                    b = app.t_return;
                    %                     lb_commute = [app.Pmin_ev*(ones(1,app.t_leave-1)) 0*ones(1,app.t_return-app.t_leave+1) app.Pmin_ev*(ones(1,app.dim1-app.t_return))]
                    %                     ub_commute = [app.Pmax_ev*(ones(1,app.t_leave-1)) 0*ones(1,app.t_return-app.t_leave+1) app.Pmax_ev*(ones(1,app.dim1-app.t_return))]
                    lb_commute = [app.Pmin_ev*(ones(1,a-1)) 0*ones(1,b-a+1) app.Pmin_ev*(ones(1,app.dim1-b))];
                    ub_commute = [app.Pmax_ev*(ones(1,a-1)) 0*ones(1,b-a+1) app.Pmax_ev*(ones(1,app.dim1-b))];
                elseif app.commute_enable=="Off"
                    lb_commute = [app.Pmin_ev*(ones(1,app.dim1))];
                    ub_commute = [app.Pmax_ev*(ones(1,app.dim1))];
                end
                lb_commute(end)=-0.0000001;
                ub_commute(end)=0.0000001;
            elseif app.EV_flag=="Off"
                app.dim1 = 0;
                lb_commute = [];
                ub_commute = [];
            end
            if app.Battery_flag=="On"
                app.dim4 = length((app.OptimisationStartTime-1)/2:app.PSO_regbat_dt:T);
            elseif app.Battery_flag=="Off"
                app.dim4 = 0;
            end
            
            if app.HeatSystem_flag=="On"
                app.dim2 = length((app.OptimisationStartTime-1)/2:app.PSO_thermal_dt:T);
            elseif app.HeatSystem_flag=="Off"
                app.dim2 = 0;
            end
            if app.Loadshift_flag=="On"
                app.dim3 = length((app.OptimisationStartTime-1)/2:app.PSO_loadshift_dt:T);
            elseif app.Loadshift_flag=="Off"
                app.dim3 = 0;
            end
            
            lb=[lb_commute app.QEC_min*ones(1,app.dim2) Load_shifting_min*ones(1,app.dim3) app.Pmin_bat*(ones(1,app.dim4))];
            ub=[ub_commute app.QEC_max*ones(1,app.dim2) Load_shifting_max*ones(1,app.dim3) app.Pmax_bat*(ones(1,app.dim4))];
            
            
            if dumb_operation == 1
                lb=[0*(ones(1,app.dim4)) app.QEC_min*ones(1,app.dim2) 1*ones(1,app.dim3) 0*(ones(1,app.dim4))];
                ub=[0*(ones(1,app.dim4)) app.QEC_max*ones(1,app.dim2) 1*ones(1,app.dim3) 0*(ones(1,app.dim4))];
            end
            
            
            functname = 'PSOfunction';
            plotfcn='goplotpso';
            shw     = 0;   % how often to update display
            minmax=0;
            mvden = 1;    %Default = 4, can be scalar
            errgoal=NaN;
            varrange = transpose([lb;ub]);
            dims=app.dim1+app.dim2+app.dim3+app.dim4;
            modl  = 3;
            mv=[];
            for i=1:dims
                mv=[mv;(varrange(i,2)-varrange(i,1))/mvden];
            end
            
            ac      = [2.1,2.1];% acceleration constants, only used for modl=0
            Iwt     = [0.4,0.01];  % intertia weights, only used for modl=0
            wt_end  = 60; % iterations it takes to go from Iwt(1) to Iwt(2), only for modl=0
            errgrad = 1e-99;   % lowest error gradient tolerance
            errgraditer=100; % max # of epochs without error change >= errgrad
            PSOseed = 0;    % if=1 then can input particle starting positions, if= 0 then all random
            
            
            %
            %             %%%%%%%%%%%%%%%%%%%
            %
            %             app.SoCmax_bat = 10.5;
            %
            %             %%%%%%%%%%%%%%%%%%%
            
            
            psoparams=[shw app.epoch app.ps ac(1) ac(2) Iwt(1) Iwt(2) wt_end errgrad errgraditer errgoal modl PSOseed];
            pso_out=prepare_PSO(app.CostOfPower, app.CostOfPV, app.PV_Production, app.home_load, ...
                app.SoCmax_ev, app.SoCmin_ev, app.SoC_initial_ev, app.SoC_commute, app.Tmax, app.Tmin, app.T_in_initial,...
                app.Zeta, app.SoC_target_ev, app.dt, app.outdoor_temperature, app.Uwall, app.Uwindow, app.Fwall, app.Fwindow, ...
                app.Qin, app.Qsw, app.Qsg, app.dim1, app.dim2, app.dim3, app.dim4, app.heatcool_sign, app.t_leave, ...
                app.t_return, app.SoCmax_bat, app.SoCmin_bat, app.SoC_initial_bat, app.SoC_target_bat, app.EV_bat_n, app.bat_n,...
                functname, dims, mv, varrange, minmax, psoparams,plotfcn,app.OptimisationStartTime,app.EV_flag, ...
                app.Battery_flag,app.HeatSystem_flag,app.Loadshift_flag,app.COP,app.commute_enable,grid_outage,app.SellingCostOfPower);
            %             %%%%%%%%%%%%%%%%%%%
            %
            %             app.SoCmax_bat = 13.5;
            %
            %             %%%%%%%%%%%%%%%%%%%
            %
            if app.EV_flag=="On"
                PPEV_not_interp = pso_out(1:app.dim1);
                
                
                PPEV_not_interp = movmean(PPEV_not_interp,3);


                PPEV = interp1([0:T_end/(app.dim1-1):T_end],PPEV_not_interp,[0:app.dt:T_end],'spline');
                if app.commute_enable=="On"
                    
                    PPEV(2*app.t_leave:2*app.t_return) = 0;
                end
                
                state_of_charge_ev = zeros(1,length(PPEV));
                PPEV_actual = zeros(1,length(PPEV))';
                for z=1:length(PPEV_actual)
                    if PPEV(z)>=0
                        PPEV_actual(z)=PPEV(z)*app.EV_bat_n;
                    else
                        PPEV_actual(z)=PPEV(z)/app.EV_bat_n;
                    end
                end
                
                
                state_of_charge_ev(1)=app.SoC_initial_ev;
                state_of_charge_ev(2:end)=app.SoC_initial_ev+cumsum(PPEV_actual(1:end-1))*app.dt;
                
                if app.commute_enable=="On"
                    for i= 1:2*app.t_return-2*app.t_leave
                        state_of_charge_ev(2*app.t_leave+i)=state_of_charge_ev(2*app.t_leave+i)-i/((2*app.t_return-2*app.t_leave)/app.SoC_commute);
                    end
                    state_of_charge_ev(2*app.t_return+1:length(t))=state_of_charge_ev(2*app.t_return+1:length(t))-app.SoC_commute;
                end
                
            elseif app.EV_flag=="Off"
                PPEV=zeros(1,length(0:app.dt:T_end));
                state_of_charge_ev=[];
            end
            
            
            if app.Battery_flag=="On"
                PBAT_not_interp = pso_out(app.dim1+app.dim2+app.dim3+1:app.dim1+app.dim2+app.dim3+app.dim4);
                
                PBAT_not_interp = movmean(PBAT_not_interp,3);
                
                
                PBAT = interp1([0:T_end/(app.dim4-1):T_end],PBAT_not_interp,[0:app.dt:T_end],'spline');
                
                state_of_charge_bat= zeros(1,length(PBAT));
                PBAT_actual = zeros(1,length(PBAT))';
                for z=1:length(PBAT_actual)
                    if PBAT(z)>=0
                        PBAT_actual(z)=PBAT(z)*app.bat_n;
                    else
                        PBAT_actual(z)=PBAT(z)/app.bat_n;
                    end
                end
                
                state_of_charge_bat(1)=app.SoC_initial_bat;
                state_of_charge_bat(2:end) = app.SoC_initial_bat + cumsum(PBAT_actual(1:end-1))*app.dt;
                
                
            elseif app.Battery_flag=="Off"
                PBAT = zeros(1,length(0:app.dt:T_end));
                state_of_charge_bat = [];
            end


            if app.HeatSystem_flag=="On"
                QEC_not_interp = pso_out(app.dim1+1:app.dim1+app.dim2);
                QEC = interp1([0:T_end/(app.dim2-1):T_end],QEC_not_interp,[0:app.dt:T_end],'spline');
                
                indoor_temperature=zeros(1,length(QEC));
                indoor_temperature(1) =app.T_in_initial;
                
                for i=2:length(QEC)
                    
                    indoor_temperature(i) = indoor_temperature(i-1) + app.heatcool_sign*QEC(i-1)/app.Zeta + ...
                        (app.Uwall*app.Fwall*(app.outdoor_temperature(i-1)-indoor_temperature(i-1))+ ...
                        app.Uwindow*app.Fwindow*(app.outdoor_temperature(i-1)-indoor_temperature(i-1))+ ...
                        app.Qin(i-1)+app.Qsw(i-1)+app.Qsg(i-1))/app.Zeta;
                end
                
                QEC_electric = QEC/app.COP;


                indoor_temperature = movmean(indoor_temperature,3);
                
                
            elseif app.HeatSystem_flag=="Off"
                QEC = zeros(1,length(0:app.dt:T_end));
                QEC_electric=zeros(1,length(0:app.dt:T_end));
                indoor_temperature=[];
            end
            
            if app.Loadshift_flag=="On"
                PLOAD_shifting_not_interp = pso_out(app.dim1+app.dim2+1:app.dim1+app.dim2+app.dim3);
                PLOAD_shifting = interp1([0:T_end/(app.dim3-1):T_end],PLOAD_shifting_not_interp,[0:app.dt:T_end],'spline')';
                PLOAD = PLOAD_shifting.*app.home_load';
            elseif app.Loadshift_flag=="Off"
                PLOAD = app.home_load';
            end
            
            P_Grid=PPEV+QEC_electric-app.PV_Production+PLOAD'+PBAT;
            
            
            %             PPEV
            %             QEC_electric
            %             PLOAD
            %             PBAT
            
            power_sum = PPEV+QEC_electric+PLOAD'+PBAT-app.PV_Production;
            
            
            %             pvcost = sum(app.PV_Production.*app.CostOfPower)
            pvcostt = sum(app.PV_Production.*app.CostOfPV).*app.dt
            
            final_cost = 0;
            for p = 1 : 49
                if power_sum(p)>=0
                    final_cost = final_cost + (power_sum(p) * app.CostOfPower(p))*app.dt;
                else
                    final_cost = final_cost + (power_sum(p) * app.SellingCostOfPower(p))*app.dt;
                end
            end
            
            
            %             final_cost=sum((componentsPower).*app.CostOfPower-(app.PV_Production.*app.CostOfPV))*app.dt;
            display_cost = sprintf('\nCost: %0.2f€',final_cost);
            disp(display_cost)


            %Interpolation
            %
            %             figure
            %             plot(PPEV_not_interp)
            %
            %             figure
            %             plot(movmean(PPEV_not_interp,3))
            %
            %             figure
            %             plot(t,PPEV)


            t_new = 0:0.125:T;
            PPEV = interp1(t,PPEV,t_new,'spline');
            PBAT = interp1(t,PBAT,t_new,'spline');
            
            PPEV(PPEV>5) = 5;
            PPEV(PPEV<-5) = -5;
            PBAT(PBAT>5) = 5;
            PBAT(PBAT<-5) = -5;
            
            state_of_charge_ev = interp1(t,state_of_charge_ev,t_new,'spline');
            state_of_charge_bat = interp1(t,state_of_charge_bat,t_new,'spline');


            PLOAD = interp1(t,PLOAD,t_new,'spline');
            P_Grid = interp1(t,P_Grid,t_new,'spline');
            
            if grid_outage == 1
                P_Grid(125:153) = 0;
            end
            
            if frequency_support == 1
                
                state_of_charge_ev(89:end) = state_of_charge_ev(89:end) - 0.05;
                state_of_charge_bat(89:end) = state_of_charge_bat(89:end) - 0.05;
                
                PPEV(89) = PPEV(89) - 3;
                PPEV(90) = PPEV(90) - 1;
                PBAT(89) = PBAT(89) - 1.5;
                PBAT(90) = PBAT(90) - 1;
                PLOAD(89) = PLOAD(89) - 0.5;
                PLOAD(90) = PLOAD(90) - 0.25;
                P_Grid(89) = P_Grid(89) - 5;
                P_Grid(90) = P_Grid(90) - 2.25;
                
                
                PPEV(133:157) = 0;
            end
            
            %% ------------Plots---------------------------------------------------------
            
            if app.EV_flag=="On"
                
                
                figure
                set(gcf,'Position',[500 100 1100 1200])
                
                subplot(3,2,1)
                xBox = [0, 24, 24, 0];
                yBox = [0, 0, app.Pmax_ev, app.Pmax_ev];
                patch(xBox, yBox, 'black', 'FaceColor', 'green', 'FaceAlpha', 0.1);
                xBox = [0 24 24 0];
                yBox = [0 0 app.Pmin_ev app.Pmin_ev];
                patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.1);
                hold on;
                plot(t_new,PPEV,'b','LineWidth', 1.5);
                %                 plot(t,PPEV_actual,'r','LineWidth', 1.5);
                axis([0 24 app.Pmin_ev app.Pmax_ev])
                title('EV Battery Power')
                xlabel('Time (hours)')
                ylabel('Power (kW)','FontSize', 15)
                set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                    'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                    'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', app.Pmin_ev:1:app.Pmax_ev,'LineWidth', 1)
                set(gca,'fontname','times','FontSize', 15)
                if app.commute_enable=="On"
                    xBox = [app.t_leave-0.5, app.t_return-0.5, app.t_return-0.5, app.t_leave-0.5];
                    yBox = [app.Pmax_ev, app.Pmax_ev, app.Pmin_ev, app.Pmin_ev];
                    patch(xBox, yBox, 'black', 'FaceColor', 'black', 'FaceAlpha', 0.1);
                end
                if frequency_support == 1
                    %Frequency support box
                    xBox = [11, 11.1, 11.1, 11];
                    yBox = [app.Pmax_ev, app.Pmax_ev, app.Pmin_ev, app.Pmin_ev];
                    patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.3, 'EdgeColor','red', 'EdgeAlpha', 0.5);
                    legend('Charging','Discharging','P_E_V','EV Commute','Frequency Support','Location','northwest','FontSize', 12);
                    
                end
                
                if grid_outage == 1
                    xBox = [15.5, 19, 19, 15.5];
                    yBox = [app.Pmax_ev, app.Pmax_ev, app.Pmin_ev, app.Pmin_ev];
                    patch(xBox, yBox, 'black', 'FaceColor', 'black', 'FaceAlpha', 0.15, 'EdgeColor','black', 'EdgeAlpha', 0.5);
                    legend('Charging','Discharging','P_E_V','No grid','Location','northwest','FontSize', 15);
                    
                end
                set(gca,'fontname','times','FontSize', 15)
                
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})


                subplot(3,2,2)
                plot(t_new,state_of_charge_ev,'m','LineWidth', 1.5)
                hold on;
                limitline_top = app.SoCmax_ev*ones(1,length(state_of_charge_ev));
                plot(t_new,limitline_top,'r--');
                limitline_bottom = app.SoCmin_ev*ones(1,length(state_of_charge_ev));
                plot(t_new,limitline_bottom,'r--');
                hold off;
                axis([0 24 0 app.Ev_bat_capacity])
                xlabel('Time (hours)')
                ylabel('Energy (kWh)')
                title('EV Battery Energy')
                set(gca,'fontname','times','FontSize', 15)
                
                set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                    'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                    'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:5:app.Ev_bat_capacity,'LineWidth', 1)
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                if app.commute_enable=="On"
                    xBox = [app.t_leave-0.5, app.t_return-0.5, app.t_return-0.5, app.t_leave-0.5];
                    yBox = [app.SoCmax_ev, app.SoCmax_ev, app.SoCmin_ev, app.SoCmin_ev];
                    patch(xBox, yBox, 'black', 'FaceColor', 'black', 'FaceAlpha', 0.1);
                end
                if frequency_support == 1
                    %Frequency support box
                    xBox = [11, 11.1, 11.1, 11];
                    yBox = [app.SoCmax_ev, app.SoCmax_ev, app.SoCmin_ev, app.SoCmin_ev];
                    patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.3, 'EdgeColor','red', 'EdgeAlpha', 0.5);
                end
                if grid_outage == 1
                    xBox = [15.5, 19, 19, 15.5];
                    yBox = [app.SoCmax_ev, app.SoCmax_ev, app.SoCmin_ev, app.SoCmin_ev];
                    patch(xBox, yBox, 'black', 'FaceColor', 'black', 'FaceAlpha', 0.15, 'EdgeColor','black', 'EdgeAlpha', 0.5);
                    %                     legend('E_E_V','','P_E_V','No grid','Location','northwest');
                    
                end
                
            end
            
            
            if app.Battery_flag=="On"
                
                subplot(3,2,3)
                xBox = [0 0 24 24];
                yBox = [0 app.Pmax_bat app.Pmax_bat 0];
                patch(xBox, yBox, 'black', 'FaceColor', 'green', 'FaceAlpha', 0.1);
                xBox = [0 24 24 0];
                yBox = [0 0 app.Pmin_bat app.Pmin_bat];
                patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.1);
                hold on;
                plot(t_new,PBAT,'b','LineWidth', 1.5);
                axis([0 24 app.Pmin_bat app.Pmax_bat])
                title('Home Battery Power')
                xlabel('Time (hours)')
                ylabel('Power (kW)')
                set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                    'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                    'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', app.Pmin_bat:1:app.Pmax_bat,'LineWidth', 1)
                set(gca,'fontname','times','FontSize', 15)
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                if frequency_support == 1
                    %Frequency support box
                    xBox = [11, 11.1, 11.1, 11];
                    yBox = [app.Pmax_bat, app.Pmax_bat, app.Pmin_bat, app.Pmin_bat];
                    patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.3, 'EdgeColor','red', 'EdgeAlpha', 0.5);
                end
                
                if grid_outage == 1
                    xBox = [15.5, 19, 19, 15.5];
                    yBox = [app.Pmax_bat, app.Pmax_bat, app.Pmin_bat, app.Pmin_bat];
                    patch(xBox, yBox, 'black', 'FaceColor', 'black', 'FaceAlpha', 0.15, 'EdgeColor','black', 'EdgeAlpha', 0.5);
                    legend('Charging','Discharging','P_B','No grid','Location','northwest');
                    
                end
                
                subplot(3,2,4)
                plot(t_new,state_of_charge_bat,'m','LineWidth', 1.5)
                hold on;
                limitline_top = app.SoCmax_bat*ones(1,length(state_of_charge_bat));
                plot(t_new,limitline_top,'r--');
                limitline_bottom = app.SoCmin_bat*ones(1,length(state_of_charge_bat));
                plot(t_new,limitline_bottom,'r--');
                hold off;
                axis([0 24 0 app.bat_capacity])
                xlabel('Time (hours)')
                ylabel('Energy (kWh)')
                set(gca,'fontname','times','FontSize', 15)
                title('Home Battery Energy')
                set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                    'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                    'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:3:app.bat_capacity,'LineWidth', 1)
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                if frequency_support == 1
                    %Frequency support box
                    xBox = [11, 11.1, 11.1, 11];
                    yBox = [app.SoCmax_bat, app.SoCmax_bat, app.SoCmin_bat, app.SoCmin_bat];
                    patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.3, 'EdgeColor','red', 'EdgeAlpha', 0.5);
                end
                if grid_outage == 1
                    xBox = [15.5, 19, 19, 15.5];
                    yBox = [app.SoCmax_bat, app.SoCmax_bat, app.SoCmin_bat, app.SoCmin_bat];
                    patch(xBox, yBox, 'black', 'FaceColor', 'black', 'FaceAlpha', 0.15, 'EdgeColor','black', 'EdgeAlpha', 0.5);
                end
                
            end
            
            if app.HeatSystem_flag=="On"
                
                subplot(3,2,5)
                plot(t,QEC_electric,'c','LineWidth', 1.5)
                xlabel('Time (hours)')
                ylabel('Power (kW)')
                title('Electric Chiller Power')
                axis([0 24 app.QEC_min 3])
                set(gca,'fontname','times','FontSize', 15)
                
                set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                    'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                    'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', app.QEC_min:1:app.QEC_max,'LineWidth', 1)
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                
                
                subplot(3,2,6)
                plot(t,app.outdoor_temperature,'r','LineWidth', 1.5)
                hold on;
                plot(t,indoor_temperature,'b','LineWidth', 1.5)
                limitline_top = app.Tmax*ones(1,length(t));
                plot(t,limitline_top,'g--');
                limitline_bottom = app.Tmin*ones(1,length(t));
                plot(t,limitline_bottom,'g--');
                xlabel('Time (hours)')
                ylabel('Temperature (°C)')
                title('Temperature')
                axis([0 24 15 35])
                set(gca,'fontname','times','FontSize', 15)
                
                set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                    'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                    'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:5:45,'LineWidth', 1)
                legend('Outdoor','Indoor');
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                
            end
            
            
            if app.Loadshift_flag=="On"
                
                figure
                
                set(gcf,'Position',[500 400 1000 400])

                
                plot(t,app.home_load,'r','LineWidth', 1.5)
                hold on;
                plot(t_new,PLOAD,'b','LineWidth', 1.5);
                hold off;
                axis([0 24 0 2.5])
                set(gca,'fontname','times','FontSize', 15)
                
                legend('Initial Non-Thermal Load','Shifted Load')
                title('Residential Loads')
                set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                    'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                    'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:1:5,'LineWidth', 1)
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                xlabel('Time (hours)')
                ylabel('Power (kW)')
                
                %                 figure
                %                 plot(t_new,PLOAD,'r','LineWidth', 1.5)
                %                 hold on;
                %                 set(gca,'fontname','times','FontSize', 15)
                %
                %                 plot(t,QEC_electric,'c','LineWidth', 1.5)
                %                 %                 plot(t,QEC_electric+PLOAD,'m','LineWidth', 1.5)
                %                 axis([0 24 0 5])
                %                 legend('Non-Thermal Load','Cooling Load','Final Load')
                %                 title('Home Loads')
                %                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                %                     'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                %                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:1:5,'LineWidth', 1)
                %                 xticks(0:4:24)
                %                 xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                %                 xlabel('Time (hours)')
                %                 ylabel('P_L_O_A_D (kW)')
                %
                %                 if frequency_support == 1
                %                     xBox = [11, 11.1, 11.1, 11];
                %                     yBox = [5, 5, 0, 0];
                %                     patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.3, 'EdgeColor','red', 'EdgeAlpha', 0.5);
                %                 end
                
                
            end
            
            if app.solar_panels_state=="On"
                
                %                 figure
                %                 plot (t,app.PV_Production,'y','LineWidth', 1.5)
                %                 axis([0 24 min(app.PV_Production) max(app.PV_Production)])
                %                 %                 title('Solar Panel Power')
                %                 set(gca,'fontname','times','FontSize', 15)
                %
                %                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                %                     'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                %                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:2:8,'LineWidth', 1)
                %                 xticks(0:4:24)
                %                 xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                %                 xlabel('Time (hours)')
                %                 ylabel('Power (kW)')
                %
            end
            
            figure
            set(gcf,'Position',[500 400 1000 400])
            axis([0 24 app.P_grid_min app.P_grid_max])
            title('Grid Power Exchange')
            set(gca,'fontname','times','FontSize', 15)
            hold on;
            xBox3 = [0 0 24 24];
            yBox3 = [0 app.P_grid_max app.P_grid_max 0];
            patch(xBox3, yBox3, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.1);
            xBox4 = [0 24 24 0];
            yBox4 = [0 0 app.P_grid_min app.P_grid_min];
            patch(xBox4, yBox4, 'black', 'FaceColor', 'green', 'FaceAlpha', 0.1);
            plot (t_new,P_Grid,'b','LineWidth', 1.5)
            
            set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
                'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
                'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', -10:2:10,'LineWidth', 1)
            xticks(0:4:24)
            xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
            
            %             text(9,5,'Buy from the grid', 'FontName', 'times');
            %             text(9.8,-5,'Sell to the grid', 'FontName', 'times');
            hold off;
            xlabel({'Time (hours)';'(b)'})
            ylabel('Power (kW)')
            if frequency_support == 1
                xBox = [11, 11.1, 11.1, 11];
                yBox = [10, 10, -10, -10];
                patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.3, 'EdgeColor','red', 'EdgeAlpha', 0.5);
            end
            
            if grid_outage == 1
                xBox = [15.5, 19, 19, 15.5];
                yBox = [10, 10, -10, -10];
                patch(xBox, yBox, 'black', 'FaceColor', 'black', 'FaceAlpha', 0.15, 'EdgeColor','black', 'EdgeAlpha', 0.5);
                legend('Purchase energy from the grid','Sell energy to the grid','P_G','Islanded operation','Location','northwest');
                
            end
            
            %
            %             figure
            %             plot(t,app.CostOfPower,'r','LineWidth', 1.5)
            %             set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                 'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
            %                 'XColor', [.3 .3 .3], 'YTick', min(app.CostOfPower):0.01:max(app.CostOfPower),'LineWidth', 1)
            %             set(gca,'fontname','times','FontSize', 15)
            %             hold on;
            %             axis([0 24 0.1 0.17])
            %             ylabel('Cost (€ / kWh)')
            %             set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                 'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
            %                 'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0.1:0.01:0.17,'LineWidth', 1)
            %             title('Cost of Power')
            %             xlabel('Time (hours)')
            %             xticks(0:4:24)
            %             xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
            
            %             cost_temp=app.CostOfPower;
            %             outdoor_temperature_temp = app.outdoor_temperature;
            %             home_load_temp = app.home_load;
            %             PV_Production_temp = app.PV_Production;
            %             save('EMS_paper','t','PPEV','state_of_charge_ev','PBAT','state_of_charge_bat','cost_temp','QEC_electric', ...
            %                 'outdoor_temperature_temp','indoor_temperature','home_load_temp','PLOAD','PV_Production_temp','cost_temp','final_cost')
            %
            %
            
            
        end

        % Value changed function: SolarPanelRatedPowerkWEditField
        function SolarPanelRatedPowerkWEditFieldValueChanged(app, event)
            app.solar_panel_power = app.SolarPanelRatedPowerkWEditField.Value;
            
        end

        % Value changed function: SolarPanelsSwitch
        function SolarPanelsSwitchValueChanged(app, event)
            app.solar_panels_state = app.SolarPanelsSwitch.Value;
            if app.SolarPanelsSwitch.Value=="Off"
                app.SolarPanelRatedPowerkWEditField.Enable="Off";
                app.SellingcostofPVPowerkWhEditField.Enable="Off";
            end
            if app.SolarPanelsSwitch.Value=="On"
                app.SolarPanelRatedPowerkWEditField.Enable="On";
                app.SellingcostofPVPowerkWhEditField.Enable="On";
            end
        end

        % Value changed function: P_maxkWEditField
        function P_maxkWEditFieldValueChanged(app, event)
            app.Pmax_ev = app.P_maxkWEditField.Value;
            
        end

        % Value changed function: P_minkWEditField
        function P_minkWEditFieldValueChanged(app, event)
            app.Pmin_ev = app.P_minkWEditField.Value;
            
        end

        % Value changed function: SoC_initialkWhEditField
        function SoC_initialkWhEditFieldValueChanged(app, event)
            app.SoC_initial_ev = app.SoC_initialkWhEditField.Value;
            
        end

        % Value changed function: ConverterEfficiencyEditField
        function ConverterEfficiencyEditFieldValueChanged(app, event)
            app.EV_bat_n = app.ConverterEfficiencyEditField.Value;
            
        end

        % Value changed function: SoC_targetkWhEditField
        function SoC_targetkWhEditFieldValueChanged(app, event)
            app.SoC_target_ev = app.SoC_targetkWhEditField.Value;
            
        end

        % Value changed function: SoC_commutekWhEditField
        function SoC_commutekWhEditFieldValueChanged(app, event)
            app.SoC_commute = str2double(app.SoC_commutekWhEditField.Value);
            
        end

        % Value changed function: P_maxkWEditField_2
        function P_maxkWEditField_2ValueChanged(app, event)
            app.Pmax_bat = app.P_maxkWEditField_2.Value;
            
        end

        % Value changed function: P_minkWEditField_2
        function P_minkWEditField_2ValueChanged(app, event)
            app.Pmin_bat = app.P_minkWEditField_2.Value;
            
        end

        % Value changed function: SoC_initialkWhEditField_2
        function SoC_initialkWhEditField_2ValueChanged(app, event)
            app.SoC_initial_bat = app.SoC_initialkWhEditField_2.Value;
            
        end

        % Value changed function: SoC_targetkWhEditField_2
        function SoC_targetkWhEditField_2ValueChanged(app, event)
            app.SoC_target_bat = app.SoC_targetkWhEditField_2.Value;
            
        end

        % Value changed function: ConverterEfficiencyEditField_2
        function ConverterEfficiencyEditField_2ValueChanged(app, event)
            app.bat_n = app.ConverterEfficiencyEditField_2.Value;
            
        end

        % Value changed function: SellingcostofPVPowerkWhEditField
        function SellingcostofPVPowerkWhEditFieldValueChanged(app, event)
            app.CostOfPV = app.SellingcostofPVPowerkWhEditField.Value;
            
        end

        % Value changed function: LoadShiftingEditField
        function LoadShiftingEditFieldValueChanged(app, event)
            app.Load_shifting = app.LoadShiftingEditField.Value;
            
        end

        % Value changed function: TimestepdthoursEditField
        function TimestepdthoursEditFieldValueChanged(app, event)
            app.dt = app.TimestepdthoursEditField.Value;
            
        end

        % Value changed function: P_grid_maxkWEditField
        function P_grid_maxkWEditFieldValueChanged(app, event)
            app.P_grid_max = app.P_grid_maxkWEditField.Value;
            
        end

        % Value changed function: P_grid_minkWEditField
        function P_grid_minkWEditFieldValueChanged(app, event)
            app.P_grid_min = app.P_grid_minkWEditField.Value;
            
        end

        % Value changed function: AirDensitykgm3EditField
        function AirDensitykgm3EditFieldValueChanged(app, event)
            app.V = app.AirDensitykgm3EditField.Value;
            
        end

        % Value changed function: HeatCapacityCkJkgCEditField
        function HeatCapacityCkJkgCEditFieldValueChanged(app, event)
            app.C = app.HeatCapacityCkJkgCEditField.Value;
            
        end

        % Value changed function: AirVolumeVm3EditField
        function AirVolumeVm3EditFieldValueChanged(app, event)
            app.rho = app.AirVolumeVm3EditField.Value;
            
        end

        % Value changed function: awEditField
        function awEditFieldValueChanged(app, event)
            app.aw = app.awEditField.Value;
            
        end

        % Value changed function: R_sejm2CWEditField
        function R_sejm2CWEditFieldValueChanged(app, event)
            app.Rsej = app.R_sejm2CWEditField.Value;
            
        end

        % Value changed function: SCEditField
        function SCEditFieldValueChanged(app, event)
            app.SC = app.SCEditField.Value;
            
        end

        % Value changed function: U_wallkWm2CEditField
        function U_wallkWm2CEditFieldValueChanged(app, event)
            app.Uwall = app.U_wallkWm2CEditField.Value;
            
        end

        % Value changed function: U_windowkWm2CEditField
        function U_windowkWm2CEditFieldValueChanged(app, event)
            app.Uwindow = app.U_windowkWm2CEditField.Value;
            
        end

        % Value changed function: WallSurfacem2EditField
        function WallSurfacem2EditFieldValueChanged(app, event)
            app.Fwall = app.WallSurfacem2EditField.Value;
            
        end

        % Value changed function: WindowSurfacem2EditField
        function WindowSurfacem2EditFieldValueChanged(app, event)
            app.Fwindow = app.WindowSurfacem2EditField.Value;
            
        end

        % Value changed function: windowEditField
        function windowEditFieldValueChanged(app, event)
            app.taf_window = app.windowEditField.Value;
            
        end

        % Value changed function: InitialIndoorTemperatureCEditField
        function InitialIndoorTemperatureCEditFieldValueChanged(app, event)
            app.T_in_initial = app.InitialIndoorTemperatureCEditField.Value;
            
        end

        % Value changed function: MaxIndoorTemperatureCEditField
        function MaxIndoorTemperatureCEditFieldValueChanged(app, event)
            app.Tmax = app.MaxIndoorTemperatureCEditField.Value;
            
        end

        % Value changed function: MinIndoorTemperatureCEditField
        function MinIndoorTemperatureCEditFieldValueChanged(app, event)
            app.Tmin = app.MinIndoorTemperatureCEditField.Value;
            
        end

        % Value changed function: MaxHeatingCoolingPowerkWEditField
        function MaxHeatingCoolingPowerkWEditFieldValueChanged(app, event)
            app.QEC_max = app.MaxHeatingCoolingPowerkWEditField.Value;
            
        end

        % Value changed function: MinHeatingCoolingPowerkWEditField
        function MinHeatingCoolingPowerkWEditFieldValueChanged(app, event)
            app.QEC_min = app.MinHeatingCoolingPowerkWEditField.Value;
            
        end

        % Value changed function: COPEditField
        function COPEditFieldValueChanged(app, event)
            app.COP = app.COPEditField.Value;
            
        end

        % Value changed function: PSOParticlesEditField
        function PSOParticlesEditFieldValueChanged(app, event)
            app.ps =app.PSOParticlesEditField.Value;
            
        end

        % Value changed function: PSOEpochsEditField
        function PSOEpochsEditFieldValueChanged(app, event)
            app.epoch = app.PSOEpochsEditField.Value;
            
        end

        % Value changed function: OptimisationstartDropDown
        function OptimisationstartDropDownValueChanged(app, event)
            app.OptimisationStartTime = str2double(app.OptimisationstartDropDown.Value);
            
        end

        % Value changed function: EVBatterydtDropDown
        function EVBatterydtDropDownValueChanged(app, event)
            app.PSO_evbat_dt = str2double(app.EVBatterydtDropDown.Value);
            
        end

        % Value changed function: ThermaldtDropDown
        function ThermaldtDropDownValueChanged(app, event)
            app.PSO_thermal_dt = str2double(app.ThermaldtDropDown.Value);
            
        end

        % Value changed function: LoadshiftingdtDropDown
        function LoadshiftingdtDropDownValueChanged(app, event)
            app.PSO_loadshift_dt = str2double(app.LoadshiftingdtDropDown.Value);
            
        end

        % Value changed function: RegularBatterydtDropDown
        function RegularBatterydtDropDownValueChanged(app, event)
            app.PSO_regbat_dt = str2double(app.RegularBatterydtDropDown.Value);
        end

        % Value changed function: 
        % EVDeparturehoursinceoptimisationstartSpinner
        function EVDeparturehoursinceoptimisationstartSpinnerValueChanged(app, event)
            app.t_leave = app.EVDeparturehoursinceoptimisationstartSpinner.Value+1;
            if (app.EVDeparturehoursinceoptimisationstartSpinner.Value)>(app.EVReturnhoursinceoptimisationstartSpinner.Value)
                app.EVReturnhoursinceoptimisationstartSpinner.Value = app.EVDeparturehoursinceoptimisationstartSpinner.Value;
            end
        end

        % Value changed function: 
        % EVReturnhoursinceoptimisationstartSpinner
        function EVReturnhoursinceoptimisationstartSpinnerValueChanged(app, event)
            app.t_return = app.EVReturnhoursinceoptimisationstartSpinner.Value+1;
            
        end

        % Value changed function: ElectricVehicleSwitch
        function ElectricVehicleSwitchValueChanged(app, event)
            app.EV_flag = app.ElectricVehicleSwitch.Value;
            if app.ElectricVehicleSwitch.Value=="Off"
                app.P_maxkWEditField.Enable="Off";
                app.P_minkWEditField.Enable="Off";
                app.EVBatteryCapacitykWhEditField.Enable="Off";
                app.SoC_initialkWhEditField.Enable="Off";
                app.SoC_targetkWhEditField.Enable="Off";
                app.ConverterEfficiencyEditField.Enable="Off";
                app.SoC_commutekWhEditField.Enable="Off";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="Off";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="Off";
            end
            if app.ElectricVehicleSwitch.Value=="On"
                app.P_maxkWEditField.Enable="On";
                app.P_minkWEditField.Enable="On";
                app.EVBatteryCapacitykWhEditField.Enable="On";
                app.SoC_initialkWhEditField.Enable="On";
                app.SoC_targetkWhEditField.Enable="On";
                app.ConverterEfficiencyEditField.Enable="On";
                app.SoC_commutekWhEditField.Enable="On";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="On";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="On";
            end
        end

        % Value changed function: BatterySwitch
        function BatterySwitchValueChanged(app, event)
            app.Battery_flag = app.BatterySwitch.Value;
            if app.BatterySwitch.Value == "Off"
                app.P_maxkWEditField_2.Enable="Off";
                app.P_minkWEditField_2.Enable="Off";
                app.BatteryCapacitykWhEditField.Enable="Off";
                app.SoC_initialkWhEditField_2.Enable="Off";
                app.SoC_targetkWhEditField_2.Enable="Off";
                app.ConverterEfficiencyEditField_2.Enable="Off";
            end
            if app.BatterySwitch.Value == "On"
                app.P_maxkWEditField_2.Enable="On";
                app.P_minkWEditField_2.Enable="On";
                app.BatteryCapacitykWhEditField.Enable="On";
                app.SoC_initialkWhEditField_2.Enable="On";
                app.SoC_targetkWhEditField_2.Enable="On";
                app.ConverterEfficiencyEditField_2.Enable="On";
            end
            
        end

        % Value changed function: ShiftingSwitch
        function ShiftingSwitchValueChanged(app, event)
            app.Loadshift_flag = app.ShiftingSwitch.Value;
            if app.ShiftingSwitch.Value == "Off"
                app.LoadShiftingEditField.Enable="Off";
            end
            if app.ShiftingSwitch.Value == "On"
                app.LoadShiftingEditField.Enable="On";
            end
        end

        % Value changed function: HeatingSystemSwitch
        function HeatingSystemSwitchValueChanged(app, event)
            app.HeatSystem_flag = app.HeatingSystemSwitch.Value;
            if app.HeatingSystemSwitch.Value=="Off"
                app.AirDensitykgm3EditField.Enable="Off";
                app.HeatCapacityCkJkgCEditField.Enable="Off";
                app.AirVolumeVm3EditField.Enable="Off";
                app.awEditField.Enable="Off";
                app.R_sejm2CWEditField.Enable="Off";
                app.U_wallkWm2CEditField.Enable="Off";
                app.U_windowkWm2CEditField.Enable="Off";
                app.WallSurfacem2EditField.Enable="Off";
                app.WindowSurfacem2EditField.Enable="Off";
                app.windowEditField.Enable="Off";
                app.SCEditField.Enable="Off";
                app.InitialIndoorTemperatureCEditField.Enable="Off";
                app.MaxIndoorTemperatureCEditField.Enable="Off";
                app.MinIndoorTemperatureCEditField.Enable="Off";
                app.MaxHeatingCoolingPowerkWEditField.Enable="Off";
                app.MinHeatingCoolingPowerkWEditField.Enable="Off";
                app.COPEditField.Enable="Off";
            end
            if app.HeatingSystemSwitch.Value=="On"
                app.AirDensitykgm3EditField.Enable="On";
                app.HeatCapacityCkJkgCEditField.Enable="On";
                app.AirVolumeVm3EditField.Enable="On";
                app.awEditField.Enable="On";
                app.R_sejm2CWEditField.Enable="On";
                app.U_wallkWm2CEditField.Enable="On";
                app.U_windowkWm2CEditField.Enable="On";
                app.WallSurfacem2EditField.Enable="On";
                app.WindowSurfacem2EditField.Enable="On";
                app.windowEditField.Enable="On";
                app.SCEditField.Enable="On";
                app.InitialIndoorTemperatureCEditField.Enable="On";
                app.MaxIndoorTemperatureCEditField.Enable="On";
                app.MinIndoorTemperatureCEditField.Enable="On";
                app.MaxHeatingCoolingPowerkWEditField.Enable="On";
                app.MinHeatingCoolingPowerkWEditField.Enable="On";
                app.COPEditField.Enable="On";
            end
        end

        % Value changed function: BatteryCapacitykWhEditField
        function BatteryCapacitykWhEditFieldValueChanged(app, event)
            app.bat_capacity = app.BatteryCapacitykWhEditField.Value;
            
        end

        % Value changed function: EVBatteryCapacitykWhEditField
        function EVBatteryCapacitykWhEditFieldValueChanged(app, event)
            app.Ev_bat_capacity = app.EVBatteryCapacitykWhEditField.Value;
            
        end

        % Value changed function: AdvancedSettingsCheckBox
        function AdvancedSettingsCheckBoxValueChanged(app, event)
            if app.AdvancedSettingsCheckBox.Value==1
                app.AdvancedSettingsPanel.Visible="On";
                app.ThermodynamicModelPanel.Visible="Off";
            else
                app.AdvancedSettingsPanel.Visible="Off";
                app.ThermodynamicModelPanel.Visible="On";
            end
        end

        % Value changed function: CommuteSwitch
        function CommuteSwitchValueChanged(app, event)
            app.commute_enable = app.CommuteSwitch.Value;
            if app.CommuteSwitch.Value == "Off"
                app.SoC_commutekWhEditField.Enable="Off";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="Off";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="Off";
            elseif app.CommuteSwitch.Value == "On"
                app.SoC_commutekWhEditField.Enable="On";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="On";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="On";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Tag = 'ExecuteButton';
            app.ExecuteButton.Text = 'Execute';
            app.ElectricVehicleBatteryPanel = uipanel(app.UIFigure);
            app.ElectricVehicleBatteryPanel.Title = 'Electric Vehicle Battery';
            app.P_maxkWEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.P_maxkWEditFieldLabel.Text = 'P_max (kW)';
            app.P_maxkWEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.P_maxkWEditField.ValueChangedFcn = createCallbackFcn(app, @P_maxkWEditFieldValueChanged, true);
            app.P_maxkWEditField.Value = 5;
            app.P_minkWEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.P_minkWEditFieldLabel.Text = 'P_min (kW)';
            app.P_minkWEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.P_minkWEditField.ValueChangedFcn = createCallbackFcn(app, @P_minkWEditFieldValueChanged, true);
            app.P_minkWEditField.Value = -5;
            app.EVBatteryCapacitykWhEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.EVBatteryCapacitykWhEditFieldLabel.Text = 'EV Battery Capacity (kWh)';
            app.EVBatteryCapacitykWhEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.EVBatteryCapacitykWhEditField.ValueChangedFcn = createCallbackFcn(app, @EVBatteryCapacitykWhEditFieldValueChanged, true);
            app.EVBatteryCapacitykWhEditField.Value = 35;
            app.SoC_initialkWhEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.SoC_initialkWhEditFieldLabel.Text = 'SoC_initial (kWh)';
            app.SoC_initialkWhEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.SoC_initialkWhEditField.ValueChangedFcn = createCallbackFcn(app, @SoC_initialkWhEditFieldValueChanged, true);
            app.SoC_initialkWhEditField.Value = 15;
            app.ConverterEfficiencyEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.ConverterEfficiencyEditFieldLabel.Text = 'Converter Efficiency';
            app.ConverterEfficiencyEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.ConverterEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @ConverterEfficiencyEditFieldValueChanged, true);
            app.ConverterEfficiencyEditField.Value = 0.9;
            app.CommutesettingsPanel = uipanel(app.ElectricVehicleBatteryPanel);
            app.CommutesettingsPanel.Title = 'Commute settings';
            app.SoC_commutekWhEditFieldLabel = uilabel(app.CommutesettingsPanel);
            app.SoC_commutekWhEditFieldLabel.Text = 'SoC_commute (kWh)';
            app.SoC_commutekWhEditField = uieditfield(app.CommutesettingsPanel, 'text');
            app.SoC_commutekWhEditField.ValueChangedFcn = createCallbackFcn(app, @SoC_commutekWhEditFieldValueChanged, true);
            app.SoC_commutekWhEditField.Tooltip = {''};
            app.SoC_commutekWhEditField.Value = '3';
            app.EVDeparturehoursinceoptimisationstartSpinnerLabel = uilabel(app.CommutesettingsPanel);
            app.EVDeparturehoursinceoptimisationstartSpinnerLabel.Text = {'EV Departure hour '; '(since optimisation start)'};
            app.EVDeparturehoursinceoptimisationstartSpinner = uispinner(app.CommutesettingsPanel);
            app.EVDeparturehoursinceoptimisationstartSpinner.Limits = [0 23.5];
            app.EVDeparturehoursinceoptimisationstartSpinner.ValueChangedFcn = createCallbackFcn(app, @EVDeparturehoursinceoptimisationstartSpinnerValueChanged, true);
            app.EVDeparturehoursinceoptimisationstartSpinner.Value = 16;
            app.EVReturnhoursinceoptimisationstartSpinnerLabel = uilabel(app.CommutesettingsPanel);
            app.EVReturnhoursinceoptimisationstartSpinnerLabel.Text = {'EV Return hour '; '(since optimisation start)'};
            app.EVReturnhoursinceoptimisationstartSpinner = uispinner(app.CommutesettingsPanel);
            app.EVReturnhoursinceoptimisationstartSpinner.Limits = [0 23.5];
            app.EVReturnhoursinceoptimisationstartSpinner.ValueChangedFcn = createCallbackFcn(app, @EVReturnhoursinceoptimisationstartSpinnerValueChanged, true);
            app.EVReturnhoursinceoptimisationstartSpinner.Value = 19;
            app.CommuteSwitchLabel = uilabel(app.CommutesettingsPanel);
            app.CommuteSwitchLabel.Text = 'Commute';
            app.CommuteSwitch = uiswitch(app.CommutesettingsPanel, 'slider');
            app.CommuteSwitch.ValueChangedFcn = createCallbackFcn(app, @CommuteSwitchValueChanged, true);
            app.SoC_targetkWhEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.SoC_targetkWhEditFieldLabel.Text = 'SoC_target (kWh)';
            app.SoC_targetkWhEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.SoC_targetkWhEditField.ValueChangedFcn = createCallbackFcn(app, @SoC_targetkWhEditFieldValueChanged, true);
            app.SoC_targetkWhEditField.Value = 15;
            app.ElectricVehicleSwitchLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.ElectricVehicleSwitchLabel.Text = 'Electric Vehicle';
            app.ElectricVehicleSwitch = uiswitch(app.ElectricVehicleBatteryPanel, 'slider');
            app.ElectricVehicleSwitch.ValueChangedFcn = createCallbackFcn(app, @ElectricVehicleSwitchValueChanged, true);
            app.ElectricVehicleSwitch.Value = 'On';
            app.RegularBatteryPanel = uipanel(app.UIFigure);
            app.RegularBatteryPanel.Title = 'Regular Battery';
            app.P_maxkWEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.P_maxkWEditField_2Label.Text = 'P_max (kW)';
            app.P_maxkWEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.P_maxkWEditField_2.ValueChangedFcn = createCallbackFcn(app, @P_maxkWEditField_2ValueChanged, true);
            app.P_maxkWEditField_2.Value = 3;
            app.P_minkWEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.P_minkWEditField_2Label.Text = 'P_min (kW)';
            app.P_minkWEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.P_minkWEditField_2.ValueChangedFcn = createCallbackFcn(app, @P_minkWEditField_2ValueChanged, true);
            app.P_minkWEditField_2.Value = -3;
            app.BatteryCapacitykWhEditFieldLabel = uilabel(app.RegularBatteryPanel);
            app.BatteryCapacitykWhEditFieldLabel.Text = 'Battery Capacity (kWh)';
            app.BatteryCapacitykWhEditField = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.BatteryCapacitykWhEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryCapacitykWhEditFieldValueChanged, true);
            app.BatteryCapacitykWhEditField.Value = 15;
            app.SoC_initialkWhEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.SoC_initialkWhEditField_2Label.Text = 'SoC_initial (kWh)';
            app.SoC_initialkWhEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.SoC_initialkWhEditField_2.ValueChangedFcn = createCallbackFcn(app, @SoC_initialkWhEditField_2ValueChanged, true);
            app.SoC_initialkWhEditField_2.Value = 6;
            app.ConverterEfficiencyEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.ConverterEfficiencyEditField_2Label.Text = 'Converter Efficiency';
            app.ConverterEfficiencyEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.ConverterEfficiencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @ConverterEfficiencyEditField_2ValueChanged, true);
            app.ConverterEfficiencyEditField_2.Value = 0.9;
            app.SoC_targetkWhEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.SoC_targetkWhEditField_2Label.Text = 'SoC_target (kWh)';
            app.SoC_targetkWhEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.SoC_targetkWhEditField_2.ValueChangedFcn = createCallbackFcn(app, @SoC_targetkWhEditField_2ValueChanged, true);
            app.SoC_targetkWhEditField_2.Value = 6;
            app.BatterySwitchLabel = uilabel(app.RegularBatteryPanel);
            app.BatterySwitchLabel.Text = 'Battery';
            app.BatterySwitch = uiswitch(app.RegularBatteryPanel, 'slider');
            app.BatterySwitch.ValueChangedFcn = createCallbackFcn(app, @BatterySwitchValueChanged, true);
            app.BatterySwitch.Value = 'On';
            app.SolarPanels = uipanel(app.UIFigure);
            app.SolarPanels.Title = 'Solar Panels';
            app.SolarPanelsSwitchLabel = uilabel(app.SolarPanels);
            app.SolarPanelsSwitchLabel.Text = 'Solar Panels';
            app.SolarPanelsSwitch = uiswitch(app.SolarPanels, 'slider');
            app.SolarPanelsSwitch.ValueChangedFcn = createCallbackFcn(app, @SolarPanelsSwitchValueChanged, true);
            app.SolarPanelsSwitch.Value = 'On';
            app.SolarPanelRatedPowerkWEditFieldLabel = uilabel(app.SolarPanels);
            app.SolarPanelRatedPowerkWEditFieldLabel.Text = 'Solar Panel Rated Power (kW)';
            app.SolarPanelRatedPowerkWEditField = uieditfield(app.SolarPanels, 'numeric');
            app.SolarPanelRatedPowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @SolarPanelRatedPowerkWEditFieldValueChanged, true);
            app.SolarPanelRatedPowerkWEditField.Value = 8;
            app.SellingcostofPVPowerkWhEditFieldLabel = uilabel(app.SolarPanels);
            app.SellingcostofPVPowerkWhEditFieldLabel.Text = 'Selling cost of PV Power (€/kWh)';
            app.SellingcostofPVPowerkWhEditField = uieditfield(app.SolarPanels, 'numeric');
            app.SellingcostofPVPowerkWhEditField.ValueChangedFcn = createCallbackFcn(app, @SellingcostofPVPowerkWhEditFieldValueChanged, true);
            app.SellingcostofPVPowerkWhEditField.Value = 0.065;
            app.LoadShiftingPanel = uipanel(app.UIFigure);
            app.LoadShiftingPanel.Title = 'Load Shifting';
            app.LoadShiftingEditFieldLabel = uilabel(app.LoadShiftingPanel);
            app.LoadShiftingEditFieldLabel.Text = 'Load Shifting (%)';
            app.LoadShiftingEditField = uieditfield(app.LoadShiftingPanel, 'numeric');
            app.LoadShiftingEditField.ValueChangedFcn = createCallbackFcn(app, @LoadShiftingEditFieldValueChanged, true);
            app.LoadShiftingEditField.Value = 40;
            app.ShiftingSwitchLabel = uilabel(app.LoadShiftingPanel);
            app.ShiftingSwitchLabel.Text = 'Shifting';
            app.ShiftingSwitch = uiswitch(app.LoadShiftingPanel, 'slider');
            app.ShiftingSwitch.ValueChangedFcn = createCallbackFcn(app, @ShiftingSwitchValueChanged, true);
            app.ShiftingSwitch.Value = 'On';
            app.GridPanel = uipanel(app.UIFigure);
            app.GridPanel.Title = 'Grid';
            app.P_grid_maxkWEditFieldLabel = uilabel(app.GridPanel);
            app.P_grid_maxkWEditFieldLabel.Text = 'P_grid_max (kW)';
            app.P_grid_maxkWEditField = uieditfield(app.GridPanel, 'numeric');
            app.P_grid_maxkWEditField.ValueChangedFcn = createCallbackFcn(app, @P_grid_maxkWEditFieldValueChanged, true);
            app.P_grid_maxkWEditField.Value = 10;
            app.P_grid_minkWEditFieldLabel = uilabel(app.GridPanel);
            app.P_grid_minkWEditFieldLabel.Text = 'P_grid_min (kW)';
            app.P_grid_minkWEditField = uieditfield(app.GridPanel, 'numeric');
            app.P_grid_minkWEditField.ValueChangedFcn = createCallbackFcn(app, @P_grid_minkWEditFieldValueChanged, true);
            app.P_grid_minkWEditField.Value = -10;
            app.ThermodynamicModelPanel = uipanel(app.UIFigure);
            app.ThermodynamicModelPanel.Title = 'Thermodynamic Model';
            app.AirDensitykgm3EditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.AirDensitykgm3EditFieldLabel.Text = 'Air Density ρ (kg/m^3)';
            app.AirDensitykgm3EditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.AirDensitykgm3EditField.ValueChangedFcn = createCallbackFcn(app, @AirDensitykgm3EditFieldValueChanged, true);
            app.AirDensitykgm3EditField.Value = 1.2;
            app.HeatCapacityCkJkgCEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.HeatCapacityCkJkgCEditFieldLabel.Text = 'Heat Capacity C (kJ/(kg*°C))';
            app.HeatCapacityCkJkgCEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.HeatCapacityCkJkgCEditField.ValueChangedFcn = createCallbackFcn(app, @HeatCapacityCkJkgCEditFieldValueChanged, true);
            app.HeatCapacityCkJkgCEditField.Value = 1;
            app.AirVolumeVm3EditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.AirVolumeVm3EditFieldLabel.Text = 'Air Volume V (m^3)';
            app.AirVolumeVm3EditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.AirVolumeVm3EditField.ValueChangedFcn = createCallbackFcn(app, @AirVolumeVm3EditFieldValueChanged, true);
            app.AirVolumeVm3EditField.Value = 729;
            app.U_wallkWm2CEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.U_wallkWm2CEditFieldLabel.Text = 'U_wall (kW/(m^2*°C))';
            app.U_wallkWm2CEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.U_wallkWm2CEditField.ValueChangedFcn = createCallbackFcn(app, @U_wallkWm2CEditFieldValueChanged, true);
            app.U_wallkWm2CEditField.Value = 0.00048;
            app.U_windowkWm2CEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.U_windowkWm2CEditFieldLabel.Text = 'U_window (kW/(m^2*°C))';
            app.U_windowkWm2CEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.U_windowkWm2CEditField.ValueChangedFcn = createCallbackFcn(app, @U_windowkWm2CEditFieldValueChanged, true);
            app.U_windowkWm2CEditField.Value = 0.0029;
            app.WallSurfacem2EditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.WallSurfacem2EditFieldLabel.Text = 'Wall Surface (m^2)';
            app.WallSurfacem2EditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.WallSurfacem2EditField.ValueChangedFcn = createCallbackFcn(app, @WallSurfacem2EditFieldValueChanged, true);
            app.WallSurfacem2EditField.Value = 405;
            app.WindowSurfacem2EditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.WindowSurfacem2EditFieldLabel.Text = 'Window Surface (m^2)';
            app.WindowSurfacem2EditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.WindowSurfacem2EditField.ValueChangedFcn = createCallbackFcn(app, @WindowSurfacem2EditFieldValueChanged, true);
            app.WindowSurfacem2EditField.Value = 150;
            app.awEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.awEditFieldLabel.Text = 'aw';
            app.awEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.awEditField.ValueChangedFcn = createCallbackFcn(app, @awEditFieldValueChanged, true);
            app.awEditField.Value = 18.63;
            app.R_sejm2CWEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.R_sejm2CWEditFieldLabel.Text = 'R_sej (m^2*°C/W)';
            app.R_sejm2CWEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.R_sejm2CWEditField.ValueChangedFcn = createCallbackFcn(app, @R_sejm2CWEditFieldValueChanged, true);
            app.R_sejm2CWEditField.Value = 0.04;
            app.windowEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.windowEditFieldLabel.Text = 'τ_window';
            app.windowEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.windowEditField.ValueChangedFcn = createCallbackFcn(app, @windowEditFieldValueChanged, true);
            app.windowEditField.Value = 1.1;
            app.SCEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.SCEditFieldLabel.Text = 'SC';
            app.SCEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.SCEditField.ValueChangedFcn = createCallbackFcn(app, @SCEditFieldValueChanged, true);
            app.SCEditField.Value = 0.54;
            app.HeatingCoolingsystemPanel = uipanel(app.ThermodynamicModelPanel);
            app.HeatingCoolingsystemPanel.Title = 'Heating/Cooling system';
            app.InitialIndoorTemperatureCEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.InitialIndoorTemperatureCEditFieldLabel.Text = 'Initial Indoor Temperature (°C)';
            app.InitialIndoorTemperatureCEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.InitialIndoorTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @InitialIndoorTemperatureCEditFieldValueChanged, true);
            app.InitialIndoorTemperatureCEditField.Value = 23;
            app.MaxIndoorTemperatureCEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.MaxIndoorTemperatureCEditFieldLabel.Text = 'Max Indoor Temperature (°C)';
            app.MaxIndoorTemperatureCEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.MaxIndoorTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIndoorTemperatureCEditFieldValueChanged, true);
            app.MaxIndoorTemperatureCEditField.Value = 23;
            app.MinIndoorTemperatureCEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.MinIndoorTemperatureCEditFieldLabel.Text = 'Min Indoor Temperature (°C)';
            app.MinIndoorTemperatureCEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.MinIndoorTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @MinIndoorTemperatureCEditFieldValueChanged, true);
            app.MinIndoorTemperatureCEditField.Value = 20;
            app.MaxHeatingCoolingPowerkWLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.MaxHeatingCoolingPowerkWLabel.Text = 'Max Heating/Cooling Power (kW)';
            app.MaxHeatingCoolingPowerkWEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.MaxHeatingCoolingPowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @MaxHeatingCoolingPowerkWEditFieldValueChanged, true);
            app.MaxHeatingCoolingPowerkWEditField.Value = 5;
            app.MinHeatingCoolingPowerkWEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.MinHeatingCoolingPowerkWEditFieldLabel.Text = 'Min Heating/Cooling Power (kW)';
            app.MinHeatingCoolingPowerkWEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.MinHeatingCoolingPowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @MinHeatingCoolingPowerkWEditFieldValueChanged, true);
            app.COPEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.COPEditFieldLabel.Text = 'COP';
            app.COPEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.COPEditField.ValueChangedFcn = createCallbackFcn(app, @COPEditFieldValueChanged, true);
            app.COPEditField.Value = 3.5;
            app.HeatingSystemSwitchLabel = uilabel(app.ThermodynamicModelPanel);
            app.HeatingSystemSwitchLabel.Text = 'Heating System';
            app.HeatingSystemSwitch = uiswitch(app.ThermodynamicModelPanel, 'slider');
            app.HeatingSystemSwitch.ValueChangedFcn = createCallbackFcn(app, @HeatingSystemSwitchValueChanged, true);
            app.HeatingSystemSwitch.Value = 'On';
            app.AdvancedSettingsPanel = uipanel(app.UIFigure);
            app.AdvancedSettingsPanel.Title = 'Advanced Settings';
            app.PSOParametersPanel = uipanel(app.AdvancedSettingsPanel);
            app.PSOParametersPanel.Title = 'PSO Parameters';
            app.EVBatterydtDropDownLabel = uilabel(app.PSOParametersPanel);
            app.EVBatterydtDropDownLabel.Text = 'EV Battery dt';
            app.EVBatterydtDropDown = uidropdown(app.PSOParametersPanel);
            app.EVBatterydtDropDown.Items = {'15 minutes', '30 minutes', '1 hour', '2 hours', '3 hours'};
            app.EVBatterydtDropDown.ItemsData = {'0.25', '0.5', '1', '2', '3'};
            app.EVBatterydtDropDown.ValueChangedFcn = createCallbackFcn(app, @EVBatterydtDropDownValueChanged, true);
            app.EVBatterydtDropDown.Value = '1';
            app.ThermaldtDropDownLabel = uilabel(app.PSOParametersPanel);
            app.ThermaldtDropDownLabel.Text = 'Thermal dt';
            app.ThermaldtDropDown = uidropdown(app.PSOParametersPanel);
            app.ThermaldtDropDown.Items = {'15 minutes', '30 minutes', '1 hour', '2 hours', '3 hours'};
            app.ThermaldtDropDown.ItemsData = {'0.25', '0.5', '1', '2', '3'};
            app.ThermaldtDropDown.ValueChangedFcn = createCallbackFcn(app, @ThermaldtDropDownValueChanged, true);
            app.ThermaldtDropDown.Value = '2';
            app.LoadshiftingdtDropDownLabel = uilabel(app.PSOParametersPanel);
            app.LoadshiftingdtDropDownLabel.Text = 'Load shifting dt';
            app.LoadshiftingdtDropDown = uidropdown(app.PSOParametersPanel);
            app.LoadshiftingdtDropDown.Items = {'15 minutes', '30 minutes', '1 hour', '2 hours', '3 hours'};
            app.LoadshiftingdtDropDown.ItemsData = {'0.25', '0.5', '1', '2', '3'};
            app.LoadshiftingdtDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadshiftingdtDropDownValueChanged, true);
            app.LoadshiftingdtDropDown.Value = '2';
            app.RegularBatterydtDropDownLabel = uilabel(app.PSOParametersPanel);
            app.RegularBatterydtDropDownLabel.Text = 'Regular Battery dt';
            app.RegularBatterydtDropDown = uidropdown(app.PSOParametersPanel);
            app.RegularBatterydtDropDown.Items = {'15 minutes', '30 minutes', '1 hour', '2 hours', '3 hours'};
            app.RegularBatterydtDropDown.ItemsData = {'0.25', '0.5', '1', '2', '3'};
            app.RegularBatterydtDropDown.ValueChangedFcn = createCallbackFcn(app, @RegularBatterydtDropDownValueChanged, true);
            app.RegularBatterydtDropDown.Value = '1';
            app.SimulationsettingsPanel = uipanel(app.AdvancedSettingsPanel);
            app.SimulationsettingsPanel.Title = 'Simulation settings';
            app.ScenarioSelectionDropDownLabel = uilabel(app.SimulationsettingsPanel);
            app.ScenarioSelectionDropDownLabel.Text = 'Scenario Selection';
            app.ScenarioSelectionDropDown = uidropdown(app.SimulationsettingsPanel);
            app.ScenarioSelectionDropDown.Items = {'Summer Morning 2-zone', 'Summer Morning Variable', 'Winter Morning 2-zone', 'Winter Morning Variable', 'Summer Evening 2-zone', 'Summer Evening Variable', 'Winter Evening 2-zone', 'Winter Evening Variable'};
            app.ScenarioSelectionDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.ScenarioSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ScenarioSelectionDropDownValueChanged, true);
            app.ScenarioSelectionDropDown.Value = '2';
            app.TimestepdthoursEditFieldLabel = uilabel(app.SimulationsettingsPanel);
            app.TimestepdthoursEditFieldLabel.Text = 'Time step dt (hours)';
            app.TimestepdthoursEditField = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.TimestepdthoursEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepdthoursEditFieldValueChanged, true);
            app.TimestepdthoursEditField.Value = 0.5;
            app.AdvancedSettingsCheckBox = uicheckbox(app.UIFigure);
            app.AdvancedSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedSettingsCheckBoxValueChanged, true);
            app.AdvancedSettingsCheckBox.Text = 'Advanced Settings';
            app.OptimisationstartDropDownLabel = uilabel(app.UIFigure);
            app.OptimisationstartDropDownLabel.Text = 'Optimisation start';
            app.OptimisationstartDropDown = uidropdown(app.UIFigure);
            app.OptimisationstartDropDown.Items = {'00:00', '00:30', '01:00', '01:30', '02:00', '02:30', '03:00', '03:30', '04:00', '04:30', '05:00', '05:30', '06:00', '06:30', '07:00', '07:30', '08:00', '08:30', '09:00', '09:30', '10:00', '10:30', '11:00', '11:30,', '12:00', '12:30', '13:00', '13:30', '14:00', '14:30', '15:00', '15:30', '16:00', '16:30', '17:00', '17:30', '18:00', '18:30', '19:00', '19:30', '20:00', '20:30', '21:00', '21:30', '22:00', '22:30', '23:00', '23:30'};
            app.OptimisationstartDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49'};
            app.OptimisationstartDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimisationstartDropDownValueChanged, true);
            app.OptimisationstartDropDown.Value = '1';
            app.PSOParticlesEditFieldLabel = uilabel(app.UIFigure);
            app.PSOParticlesEditFieldLabel.Text = 'PSO Particles';
            app.PSOParticlesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PSOParticlesEditField.ValueChangedFcn = createCallbackFcn(app, @PSOParticlesEditFieldValueChanged, true);
            app.PSOParticlesEditField.Value = 400;
            app.PSOEpochsEditFieldLabel = uilabel(app.UIFigure);
            app.PSOEpochsEditFieldLabel.Text = 'PSO Epochs';
            app.PSOEpochsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PSOEpochsEditField.ValueChangedFcn = createCallbackFcn(app, @PSOEpochsEditFieldValueChanged, true);
            app.PSOEpochsEditField.Value = 1000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerExchangeOptimisationAlgorithm_UserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end